classdef robot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        CoordinateScaleEditField  matlab.ui.control.NumericEditField
        CoordinateScaleLabel      matlab.ui.control.Label
        ShapeScaleEditField       matlab.ui.control.NumericEditField
        ShapeScaleLabel           matlab.ui.control.Label
        Joint7EditField           matlab.ui.control.NumericEditField
        Joint7EditFieldLabel      matlab.ui.control.Label
        Joint6EditField           matlab.ui.control.NumericEditField
        Joint6EditFieldLabel      matlab.ui.control.Label
        Joint5EditField           matlab.ui.control.NumericEditField
        Joint5EditFieldLabel      matlab.ui.control.Label
        Joint4EditField           matlab.ui.control.NumericEditField
        Joint4EditFieldLabel      matlab.ui.control.Label
        Joint3EditField           matlab.ui.control.NumericEditField
        Joint3EditFieldLabel      matlab.ui.control.Label
        Joint2EditField           matlab.ui.control.NumericEditField
        Joint2EditFieldLabel      matlab.ui.control.Label
        Joint1EditField           matlab.ui.control.NumericEditField
        Joint1EditFieldLabel      matlab.ui.control.Label
        TESTButton                matlab.ui.control.Button
        Slider_7                  matlab.ui.control.Slider
        Slider_6                  matlab.ui.control.Slider
        Slider_5                  matlab.ui.control.Slider
        Slider_4                  matlab.ui.control.Slider
        Slider_3                  matlab.ui.control.Slider
        Slider                    matlab.ui.control.Slider
        Slider_2                  matlab.ui.control.Slider
        UIAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        DH % Description
        limit_table
        joint_type
        num_joint
        scale_coordinate
        scale_shape
        R

    end
    
    methods (Access = private)
        
        function results = initialize_ui(app)
            app.num_joint=size(app.DH,1);
            [~,app.joint_type]=find(app.DH==inf);
            if app.num_joint>0
                app.Joint1EditField.Enable='on';
                app.Joint1EditField.Limits=app.limit_table(1,:);
                app.Slider.Enable="on";
                app.Slider.Limits=app.limit_table(1,:);
            end
            if app.num_joint>1
                app.Joint2EditField.Enable='on';
                app.Joint2EditField.Limits=app.limit_table(2,:);
                app.Slider_2.Enable="on";
                app.Slider_2.Limits=app.limit_table(2,:);
            end
            if app.num_joint>2
                app.Joint3EditField.Enable='on';
                app.Joint3EditField.Limits=app.limit_table(3,:);
                app.Slider_3.Enable="on";;
                app.Slider_3.Limits=app.limit_table(3,:);
            end
            if app.num_joint>3
                app.Joint4EditField.Enable='on';
                app.Joint4EditField.Limits=app.limit_table(4,:);
                app.Slider_4.Enable="on";
                app.Slider_4.Limits=app.limit_table(4,:);
            end
            if app.num_joint>4
                app.Joint5EditField.Enable='on';
                app.Joint5EditField.Limits=app.limit_table(5,:);
                app.Slider_5.Enable="on";
                app.Slider_5.Limits=app.limit_table(5,:);
            end
            if app.num_joint>5
                app.Joint6EditField.Enable='on';
                app.Joint6EditField.Limits=app.limit_table(6,:);
                app.Slider_6.Enable="on";
                app.Slider_6.Limits=app.limit_table(6,:);
            end
            if app.num_joint>6
                app.Joint7EditField.Enable='on';
                app.Joint7EditField.Limits=app.limit_table(7,:);
                app.Slider_7.Enable="on";
                app.Slider_7.Limits=app.limit_table(7,:);
            end
            app.DH(:,app.joint_type)=0;

            view(app.UIAxes,[45 45 30]);
            grid(app.UIAxes,'on');
            axis(app.UIAxes,'equal');
            set(app.UIAxes,'color',[0.85 0.85 0.85])
            
        end
        
        
        function results = calculate_transformations(app)
            for i=1:app.num_joint

                A(i,:,:)=Rot('Z',app.DH(i,1)*(pi/180))*Trans('Z',app.DH(i,2))*Trans('X',app.DH(i,3))*Rot('X',app.DH(i,4)*(pi/180));
            end
            for i=2:app.num_joint+1


                app.R(i,:,:)=reshape(app.R(i-1,:,:),[4 4])*reshape(A(i-1,:,:),[4 4]);
%                 if i~=app.num_joint
%                     app.R(i+1,:,:)=app.R(i,:,:);
%                 end
            end            
            
        end
        
        function results = draw_frames(app,pts,frame_num,col)
             hold(app.UIAxes,'on')
 
             %draw three axes
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,2)-pts(1,1),pts(2,2)-pts(2,1),pts(3,2)-pts(3,1),col,"LineWidth",1.5,'MaxHeadSize',0.5)
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,3)-pts(1,1),pts(2,3)-pts(2,1),pts(3,3)-pts(3,1),col,"LineWidth",1.5,'MaxHeadSize',0.5)
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,4)-pts(1,1),pts(2,4)-pts(2,1),pts(3,4)-pts(3,1),col,"LineWidth",1.5,'MaxHeadSize',0.5)
             
             %draw extension -- line for Z axis 
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,4)-pts(1,1),pts(2,4)-pts(2,1),pts(3,4)-pts(3,1),10,"--"+col,"LineWidth",.25,'ShowArrowHead','off')
             quiver3(app.UIAxes,pts(1,4),pts(2,4),pts(3,4),pts(1,1)-pts(1,4),pts(2,1)-pts(2,4),pts(3,1)-pts(3,4),10,"--"+col,"LineWidth",.25,'ShowArrowHead','off')
             
             %draw extension .. line for x axis
             quiver3(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),pts(1,2)-pts(1,1),pts(2,2)-pts(2,1),pts(3,2)-pts(3,1),10,":"+col,"LineWidth",.25,'ShowArrowHead','off')
             quiver3(app.UIAxes,pts(1,2),pts(2,2),pts(3,2),pts(1,1)-pts(1,2),pts(2,1)-pts(2,2),pts(3,1)-pts(3,2),10,":"+col,"LineWidth",.25,'ShowArrowHead','off')
             
             %label the axes
             axis_o="0_"+frame_num;
             axis_x="x_"+frame_num;
             axis_y="y_"+frame_num;
             axis_z="z_"+frame_num;
             text(app.UIAxes,pts(1,1),pts(2,1),pts(3,1),axis_o,'Color',col)
             text(app.UIAxes,pts(1,2),pts(2,2),pts(3,2),axis_x,'Color',col)
             text(app.UIAxes,pts(1,3),pts(2,3),pts(3,3),axis_y,'Color',col)
             text(app.UIAxes,pts(1,4),pts(2,4),pts(3,4),axis_z,'Color',col)

        end
        
        function results = draw_mesh(app,type,R,k)
            hold(app.UIAxes,'on')
            if type==1
                data = stlread('cylinder.stl');
            elseif type==2
                data = stlread('prism.stl');
            else 
                eror('oops! Dont o that!')
            end
            points = [data.Points(:,1)'; data.Points(:,2)'; data.Points(:,3)';ones(1,size(data.Points,1))];
            scale=[k 0 0 0;0 k 0 0; 0 0 k 0;0 0 0 1];
            points=R*scale*points;
            points=points(1:3,:);
            ndata.Points=[points(1,:)' points(2,:)' points(3,:)'];
            ndata.ConnectivityList=data.ConnectivityList;
            TO = triangulation(ndata.ConnectivityList,ndata.Points(:,1),ndata.Points(:,2),ndata.Points(:,3));
            trimesh(TO,'FaceColor','r','EdgeColor','none','FaceAlpha',.5,'Parent', app.UIAxes)           
        end
        
        function results = create_scene(app)
            hold(app.UIAxes,"off");
            cla(app.UIAxes);
            frame(1,:,:) =[0 1 0 0 
                      0 0 1 0  
                      0 0 0 1 
                      1 1 1 1 ];
%             draw_frames(app,frame_0,0,'b')
%             draw_mesh(app,app.joint_type(i),reshape(app.R(i,:,:),[4 4]),1)

            for i=1:app.num_joint+1
                frame(i,:,:)=reshape(app.R(i,:,:),[4 4])*reshape(frame(1,:,:),[4 4]);
            end
            for i=1:app.num_joint
                draw_frames(app,reshape(frame(i,:,:),[4 4]),i,'b');
                draw_mesh(app,app.joint_type(i),reshape(app.R(i,:,:),[4 4]),1);
                
            end
            draw_frames(app,reshape(frame(app.num_joint+1,:,:),[4 4]),i,'b');
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            xlim(app.UIAxes,[min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]),max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))])]);
            ylim(app.UIAxes,[min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]),max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))])]);


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, DH, joint_limits)
            app.DH=DH;
            app.limit_table=joint_limits;
            app.R(1,:,:)=eye(4);
            initialize_ui(app);
            calculate_transformations(app);
            create_scene(app);
            pause(1)
            xlim(app.UIAxes,'auto');
            ylim(app.UIAxes,'auto');
            xlim(app.UIAxes,[min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]),max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))])]);
            ylim(app.UIAxes,[min([(min((xlim(app.UIAxes)))),(min((ylim(app.UIAxes)))),min(((abs(zlim(app.UIAxes)))))]),max([(max((xlim(app.UIAxes)))),(max((ylim(app.UIAxes)))),max(((abs(zlim(app.UIAxes)))))])]);


        end

        % Button pushed function: TESTButton
        function TESTButtonPushed(app, event)

            frame_0=[0 1 0 0 
                     0 0 1 0  
                     0 0 0 1 
                     1 1 1 1 ];
            draw_frames(app,frame_0,0,'b')
            draw_mesh(app,1,eye(4),1)
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            joint_id=1;
            app.Joint1EditField.Value=value;
            app.DH(joint_id,app.joint_type(joint_id))=value;
            calculate_transformations(app);
            create_scene(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'Stick Figure')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Slider_2 = uislider(app.GridLayout);
            app.Slider_2.Enable = 'off';
            app.Slider = uislider(app.GridLayout);
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Enable = 'off';
            app.Slider_3 = uislider(app.GridLayout);
            app.Slider_3.Enable = 'off';
            app.Slider_4 = uislider(app.GridLayout);
            app.Slider_4.Enable = 'off';
            app.Slider_5 = uislider(app.GridLayout);
            app.Slider_5.Enable = 'off';
            app.Slider_6 = uislider(app.GridLayout);
            app.Slider_6.Enable = 'off';
            app.Slider_7 = uislider(app.GridLayout);
            app.Slider_7.Enable = 'off';
            app.TESTButton = uibutton(app.GridLayout, 'push');
            app.TESTButton.ButtonPushedFcn = createCallbackFcn(app, @TESTButtonPushed, true);
            app.TESTButton.Text = 'TEST';
            app.Joint1EditFieldLabel = uilabel(app.GridLayout);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint1EditField.Enable = 'off';
            app.Joint2EditFieldLabel = uilabel(app.GridLayout);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint2EditField.Enable = 'off';
            app.Joint3EditFieldLabel = uilabel(app.GridLayout);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint3EditField.Enable = 'off';
            app.Joint4EditFieldLabel = uilabel(app.GridLayout);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint4EditField.Enable = 'off';
            app.Joint5EditFieldLabel = uilabel(app.GridLayout);
            app.Joint5EditFieldLabel.Text = 'Joint 5';
            app.Joint5EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint5EditField.Enable = 'off';
            app.Joint6EditFieldLabel = uilabel(app.GridLayout);
            app.Joint6EditFieldLabel.Text = 'Joint 6';
            app.Joint6EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint6EditField.Enable = 'off';
            app.Joint7EditFieldLabel = uilabel(app.GridLayout);
            app.Joint7EditFieldLabel.Text = 'Joint 7';
            app.Joint7EditField = uieditfield(app.GridLayout, 'numeric');
            app.Joint7EditField.Enable = 'off';
            app.ShapeScaleLabel = uilabel(app.GridLayout);
            app.ShapeScaleLabel.Text = 'Shape Scale';
            app.ShapeScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.ShapeScaleEditField.Value = 1;
            app.CoordinateScaleLabel = uilabel(app.GridLayout);
            app.CoordinateScaleLabel.Text = 'Co-ordinate Scale';
            app.CoordinateScaleEditField = uieditfield(app.GridLayout, 'numeric');
            app.CoordinateScaleEditField.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = robot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end